classdef LS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StartButton                   matlab.ui.control.Button
        MovemmSlider                  matlab.ui.control.Slider
        MovemmSliderLabel             matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        DistanceEditField             matlab.ui.control.NumericEditField
        DistanceEditFieldLabel        matlab.ui.control.Label
        AcquireButton                 matlab.ui.control.Button
        NumberofImagesEditField       matlab.ui.control.NumericEditField
        NumberofImagesEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        op_states = 1;
        ConexCC;
        move_value;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConexCC = serialport("COM10",921600);
            configureTerminator(app.ConexCC,"CR/LF");
        end

        % Button pushed function: AcquireButton
        function AcquireRun(app, event)
            run_acquire(app.ConexCC, app.DistanceEditField.Value,app.NumberofImagesEditField.Value);
        end

        % Button pushed function: HomeButton
        function Call_Home(app, event)
            app.op_states = Homing(app.op_states, app.ConexCC);
        end

        % Button pushed function: StartButton
        function move_stage(app, event)
            relative_move(app.move_value, app.ConexCC);
        end

        % Value changed function: MovemmSlider
        function update_move_value(app, event)
            app.move_value = app.MovemmSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NumberofImagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofImagesEditFieldLabel.Text = 'Number of Images';
            app.NumberofImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcquireButton = uibutton(app.UIFigure, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireRun, true);
            app.AcquireButton.Text = 'Acquire';
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Call_Home, true);
            app.HomeButton.Text = {'Home'; ''};
            app.MovemmSliderLabel = uilabel(app.UIFigure);
            app.MovemmSliderLabel.Text = 'Move (mm)';
            app.MovemmSlider = uislider(app.UIFigure);
            app.MovemmSlider.Limits = [-50 50];
            app.MovemmSlider.ValueChangedFcn = createCallbackFcn(app, @update_move_value, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @move_stage, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end